classdef ModFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        LengthLabel        matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        AngleLabel         matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        PolygonareaLabel   matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        EllipseareaLabel   matlab.ui.control.Label
        Label              matlab.ui.control.Label
        MovewitharrowsPressltodrawlinePressptodrawpolygonLabel  matlab.ui.control.Label
        PlanesButtonGroup  matlab.ui.container.ButtonGroup
        ObliqueButton      matlab.ui.control.RadioButton
        CoronalButton      matlab.ui.control.RadioButton
        SagittalButton     matlab.ui.control.RadioButton
        AxialButton        matlab.ui.control.RadioButton
        ObliquePanel       matlab.ui.container.Panel
        UIAxes2_5          matlab.ui.control.UIAxes
        CoronalPanel       matlab.ui.container.Panel
        UIAxes2_4          matlab.ui.control.UIAxes
        SagittalPanel      matlab.ui.container.Panel
        UIAxes2_6          matlab.ui.control.UIAxes
        BrowseButton       matlab.ui.control.Button
        AxialPanel_2       matlab.ui.container.Panel
        UIAxes2_2          matlab.ui.control.UIAxes
    end

    
properties (Access = public)
        AxHorValue
        AxVerValue
        HorLine 
        VerLine 
        HorLineCor 
        VerLineCor
        HorLineSag 
        VerLineSag
        value
        CorHorValue
        CorVerValue
        SagHorValue
        SagVerValue
        Image3D
        size3d
        Sagittal
        Coronal
        Axial
        Oblique
        h
end

    methods (Access = private)
        
        function ChangeVerticalAxial(app)

        delete(app.VerLine);
        app.VerLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        drawnow;
        app.Sagittal = app.Image3D(:,app.AxVerValue , :);
        imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
        app.HorLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        app.VerLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        end

        function ChangeHorizontalAxial(app)

        AxialSize=size(app.Axial);
        delete(app.HorLine);
        app.HorLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        drawnow;
        if((app.AxHorValue<0)|(app.AxHorValue>AxialSize(1)))
           return;
        else
            app.Coronal = app.Image3D(app.AxHorValue , :, :);
            imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
        end
        app.HorLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        app.VerLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        end

        function ChangeVerticalSagital(app)

        delete(app.VerLineSag);
        app.VerLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        drawnow;
        app.Coronal = app.Image3D(app.SagVerValue , :, :);
        imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
        app.HorLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        app.VerLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_4, "off")
        end
        
        function ChangeHorizontalSagital(app)

        delete(app.HorLineSag);
        app.HorLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        drawnow;
        app.Axial=app.Image3D(:, :, app.SagHorValue);
        imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;
        app.HorLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        app.VerLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        end

        function ChangeVerticalCoronal(app)

        delete(app.VerLineCor);
        app.VerLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
        drawnow;
        app.Sagittal = app.Image3D(:,app.CorVerValue , :);
        imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
        app.HorLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        app.VerLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_6, "off")
        end

        function ChangeHorizontalCoronal(app)
        delete(app.HorLineCor);
        app.HorLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
        drawnow;
        app.Axial=app.Image3D(:, :, app.CorHorValue);
        imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;
        app.HorLine = yline(app.UIAxes2_2, app.AxHorValue,'g','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        app.VerLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
        hold(app.UIAxes2_2, "off")
        end
        function DrawEllipse(app)
            roi = drawellipse(app.UIAxes2_2);
            xy = roi.SemiAxes;
            x = xy(1);
            y = xy(2);
            disp(x)
            disp(y)
            e_area = x*y*pi;
            app.Label.Text = sprintf('%f',e_area);
        end
        function DrawLine(app)
        hold(app.UIAxes2_2, 'on') ;
        Line=drawline(app.UIAxes2_2);
        Pos=Line.Position;
        Z=pdist(Pos,'euclidean');
        app.Label_4.Text = sprintf('%f',Z);
        end

        function DrawPolygon(app)
        Poly=drawpolygon(app.UIAxes2_2);
        Pos=Poly.Position;
        disp(Pos)
        Area=polyarea(Pos(:,1),Pos(:,2));
        txt=sprintf('%f',Area);
        app.Label_2.Text = txt;
        end

        function Angle(app)
        Line1=drawline(app.UIAxes2_2);
        Line2=drawline(app.UIAxes2_2);
        Pos1=Line1.Position;
        Pos2=Line2.Position;
        disp(Pos1);
        disp(Pos2);
        x1=Pos1(1,1);
        y1=Pos1(1,2);
        x2=Pos1(2,1);
        y2=Pos1(2,2);
        x3=Pos2(2,1);
        y3=Pos2(2,2);

        O=[x1,y1];
        A=[x2,y2];
        B=[x3,y3];
        u = O-A ; 
        v = O-B ;  
        CosTheta = (dot(u,v) / (norm(u)*norm(v)));
        AngleNew=CosTheta*180/pi;
        %disp(AngleNew)

        v_1 = [x2,y2,0] - [x1,y1,0];
        v_2 = [x3,y3,0] - [x1,y1,0];
        Theta = atan2(norm(cross(v_1, v_2)), dot(v_1, v_2));
        NewAngle=Theta*180/3.14;
        %disp(NewAngle)

        difference = (atan((y2-x2)/(y1-x1)) - atan((y3-x2)/(x1-x1))) * 180/pi;
        %disp(difference)

        x10 = x2-x1;
        y10 = y2-y1;
        x20 = x3-x1;
        y20 = y3-y1; 
        ang = atan2(abs(x10*y20-x20*y10),x10*y10+x20*y20);
        New=ang*(180/pi);
        app.Label_3.Text = sprintf('%f',New);
        end
    end
    methods (Access = public) 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           fPath = uigetdir( 'C:\');
            files_wanted = dir(fullfile(fPath, '*.dcm'));
            NoOfSlices=length(files_wanted);
            for n=1:NoOfSlices
                File = fullfile(fPath, files_wanted(n).name);
                images{n}=dicomread(File); 
                Image2D = images{n};
                app.Image3D(:,:,n) = Image2D;
            end
            app.size3d=size(app.Image3D);
            app.Axial=app.Image3D(:, :, (app.size3d(3))./2);
            imshow(squeeze(app.Axial),[],'Parent',app.UIAxes2_2),colormap gray;
            hold on

            point = [25 35 15.5];
            normal = [0 15 25];
            app.Oblique = obliqueslice(squeeze(app.Image3D),point,normal);
            imshow(squeeze(app.Oblique),[],'Parent',app.UIAxes2_5),colormap gray;
            

            disableDefaultInteractivity(app.UIAxes2_4)
            disableDefaultInteractivity(app.UIAxes2_6)
            disableDefaultInteractivity(app.UIAxes2_2)
            app.AxVerValue = 250;
            app.AxHorValue = 250;
            app.CorVerValue = 250;
            app.CorHorValue = 100;
            app.SagVerValue = 250;
            app.SagHorValue = 100;
            app.HorLine = yline(app.UIAxes2_2,app.AxHorValue,'g','LineWidth',2);
            %app.h = drawline(app.UIAxes2_2,'Position',[0 0;500 500]);
            hold(app.UIAxes2_2, "off")
            app.VerLine = xline(app.UIAxes2_2, app.AxVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_2, "off")
              
            
            app.Sagittal = app.Image3D(:,(app.size3d(1))./2 , :);
            app.Coronal = app.Image3D((app.size3d(2))./2 , :, :);
            imshow(imrotate(squeeze(app.Coronal),90),[],'Parent',app.UIAxes2_4),colormap gray;
            app.HorLineCor = yline(app.UIAxes2_4, app.CorHorValue,'g','LineWidth',2);
            hold(app.UIAxes2_4, "off")
            app.VerLineCor = xline(app.UIAxes2_4, app.CorVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_4, "off")
            imshow(imrotate(squeeze(app.Sagittal),90),[],'Parent',app.UIAxes2_6),colormap gray;
            app.HorLineSag = yline(app.UIAxes2_6, app.SagHorValue,'g','LineWidth',2);
            hold(app.UIAxes2_6, "off")
            app.VerLineSag = xline(app.UIAxes2_6, app.SagVerValue,'r','LineWidth',2);
            hold(app.UIAxes2_6, "off")
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
        
        if app.AxialButton.Value
            if strcmp(event.Key,'rightarrow')==1
                app.ChangeVerticalAxial;
                app.AxVerValue=app.AxVerValue+5;
            elseif strcmp(event.Key, 'leftarrow')==1
                app.ChangeVerticalAxial;
                app.AxVerValue=app.AxVerValue-5;
            elseif strcmp(event.Key, 'downarrow')==1
                app.ChangeHorizontalAxial;
                app.AxHorValue=app.AxHorValue+5; 
            elseif strcmp(event.Key, 'uparrow')==1
                app.ChangeHorizontalAxial;
                app.AxHorValue=app.AxHorValue-5;
            elseif strcmp(event.Key, 'e')==1
                delete(app.HorLine)
                delete(app.VerLine)
                app.DrawEllipse;
            elseif strcmp(event.Key,'l')==1
                delete(app.HorLine)
                delete(app.VerLine)
                app.DrawLine;
            elseif strcmp(event.Key,'p')==1
                delete(app.HorLine)
                delete(app.VerLine)
                app.DrawPolygon;
            elseif strcmp(event.Key,'a')
                delete(app.HorLine)
                delete(app.VerLine)
                app.Angle
            end
        end

        if app.SagittalButton.Value
            if strcmp(event.Key,'rightarrow')==1
                app.ChangeVerticalSagital;
                app.SagVerValue=app.SagVerValue+5;
            elseif strcmp(event.Key, 'leftarrow')==1
                app.ChangeVerticalSagital;
                app.SagVerValue=app.SagVerValue-5;
            elseif strcmp(event.Key, 'downarrow')==1
                app.ChangeHorizontalSagital;
                app.SagHorValue=app.SagHorValue+5;   
            elseif strcmp(event.Key, 'uparrow')==1
                app.ChangeHorizontalSagital;
                app.SagHorValue=app.SagHorValue-5;
            end
        end

        if app.CoronalButton.Value
            if strcmp(event.Key,'rightarrow')==1
                app.CorVerValue=app.CorVerValue+5;
                app.ChangeVerticalCoronal;
            elseif strcmp(event.Key, 'leftarrow')==1
                app.CorVerValue=app.CorVerValue-5;
                app.ChangeVerticalCoronal;
            elseif strcmp(event.Key, 'downarrow')==1
                app.ChangeHorizontalCoronal;
                app.CorHorValue=app.CorHorValue+5;
            elseif strcmp(event.Key, 'uparrow')==1
                app.ChangeHorizontalCoronal;
                app.CorHorValue=app.CorHorValue-5;
            end
        end
        
       
        end

        % Callback function
        function HorizontalEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.AxialPanel_2 = uipanel(app.UIFigure);
            app.AxialPanel_2.Title = 'Axial';
            app.UIAxes2_2 = uiaxes(app.AxialPanel_2);
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XLim = [0 512];
            app.UIAxes2_2.YLim = [0 512];
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SagittalPanel = uipanel(app.UIFigure);
            app.SagittalPanel.Title = 'Sagittal';
            app.UIAxes2_6 = uiaxes(app.SagittalPanel);
            zlabel(app.UIAxes2_6, 'Z')
            app.UIAxes2_6.XLim = [0 512];
            app.UIAxes2_6.ZLim = [0 512];
            app.CoronalPanel = uipanel(app.UIFigure);
            app.CoronalPanel.Title = 'Coronal';
            app.UIAxes2_4 = uiaxes(app.CoronalPanel);
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_4.XLim = [0 512];
            app.UIAxes2_4.ZLim = [0 234];
            app.ObliquePanel = uipanel(app.UIFigure);
            app.ObliquePanel.Title = 'Oblique';
            app.UIAxes2_5 = uiaxes(app.ObliquePanel);
            zlabel(app.UIAxes2_5, 'Z')
            app.PlanesButtonGroup = uibuttongroup(app.UIFigure);
            app.PlanesButtonGroup.Title = 'Planes';
            app.AxialButton = uiradiobutton(app.PlanesButtonGroup);
            app.AxialButton.Text = 'Axial';
            app.AxialButton.Value = true;
            app.SagittalButton = uiradiobutton(app.PlanesButtonGroup);
            app.SagittalButton.Text = 'Sagittal';
            app.CoronalButton = uiradiobutton(app.PlanesButtonGroup);
            app.CoronalButton.Text = 'Coronal';
            app.ObliqueButton = uiradiobutton(app.PlanesButtonGroup);
            app.ObliqueButton.Text = 'Oblique';
            app.MovewitharrowsPressltodrawlinePressptodrawpolygonLabel = uilabel(app.UIFigure);
            app.MovewitharrowsPressltodrawlinePressptodrawpolygonLabel.Text = {'Move with arrows'; 'Press l to draw line'; 'Press p to draw polygon'; 'Press a for angle'; 'Press e to draw elipse'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EllipseareaLabel = uilabel(app.UIFigure);
            app.EllipseareaLabel.Text = 'Ellipse area';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.PolygonareaLabel = uilabel(app.UIFigure);
            app.PolygonareaLabel.Text = 'Polygon area';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.AngleLabel = uilabel(app.UIFigure);
            app.AngleLabel.Text = 'Angle';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.LengthLabel = uilabel(app.UIFigure);
            app.LengthLabel.Text = 'Length';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end